classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatternRecognition2022ProjectUIFigure  matlab.ui.Figure
        HeartDiseaseLabel               matlab.ui.control.Label
        FeatureSelectionReductionPanel  matlab.ui.container.Panel
        SelectionPanel                  matlab.ui.container.Panel
        KruskalWallisCheckBox           matlab.ui.control.CheckBox
        ReductionPanel                  matlab.ui.container.Panel
        ModelDropDown_3Label            matlab.ui.control.Label
        ModelDropDown_3                 matlab.ui.control.DropDown
        NFeaturesEditFieldLabel         matlab.ui.control.Label
        NFeaturesEditField              matlab.ui.control.NumericEditField
        PlotButton                      matlab.ui.control.Button
        ClassificationPanel             matlab.ui.container.Panel
        ClassifyButton                  matlab.ui.control.Button
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        CostEditField_4Label            matlab.ui.control.Label
        CostEditField_4                 matlab.ui.control.EditField
        GammaEditField_4Label           matlab.ui.control.Label
        GammaEditField_4                matlab.ui.control.EditField
        NeighborsEditField_4Label       matlab.ui.control.Label
        NeighborsEditField_4            matlab.ui.control.EditField
        ScenariosPanel                  matlab.ui.container.Panel
        SelectDropDownLabel             matlab.ui.control.Label
        SelectDropDown                  matlab.ui.control.DropDown
        FeatureAssessmentPanel          matlab.ui.container.Panel
        AssessButton                    matlab.ui.control.Button
        AssessButton_2                  matlab.ui.control.Button
        PreprocessingPanel              matlab.ui.container.Panel
        ScaleCheckBox                   matlab.ui.control.CheckBox
        HyperparametersTunningPanel     matlab.ui.container.Panel
        SearchButton                    matlab.ui.control.Button
        ModelDropDown_2Label            matlab.ui.control.Label
        ModelDropDown_2                 matlab.ui.control.DropDown
        NeighborsEditField_3Label       matlab.ui.control.Label
        NeighborsEditField_3            matlab.ui.control.EditField
        GammaEditField_3Label           matlab.ui.control.Label
        GammaEditField_3                matlab.ui.control.EditField
        CostEditField_3Label            matlab.ui.control.Label
        CostEditField_3                 matlab.ui.control.EditField
        NFoldsEditFieldLabel            matlab.ui.control.Label
        NFoldsEditField                 matlab.ui.control.NumericEditField
        ErrorLabel                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModelDropDown_2
        function ModelDropDown_2ValueChanged(app, event)
            value = app.ModelDropDown_2.Value;
            if strcmp(value,app.ModelDropDown_2.ItemsData(1))
                app.CostEditField_3.Visible = false;
                app.CostEditField_3Label.Visible = false;
                app.GammaEditField_3.Visible = false;
                app.GammaEditField_3Label.Visible = false;
                app.NeighborsEditField_3.Visible = true;
                app.NeighborsEditField_3Label.Visible = true;
            else
                app.NeighborsEditField_3.Visible = false;
                app.NeighborsEditField_3Label.Visible = false;
                app.CostEditField_3.Visible = true;
                app.CostEditField_3Label.Visible = true;
                app.GammaEditField_3.Visible = true;
                app.GammaEditField_3Label.Visible = true;
            end
        end

        % Value changed function: KruskalWallisCheckBox
        function KruskalWallisCheckBoxValueChanged(app, event)
            value = app.KruskalWallisCheckBox.Value;
            if value == true
                app.ModelDropDown_3.Value = "None";
                app.ModelDropDown_3.Enable = false;
            else
                app.ModelDropDown_3.Enable = true;
                app.ModelDropDown_3.Value = app.ModelDropDown_3.Items(1);
            end
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            
            if strcmp(value, "K - Nearest Neighbors")
                app.NeighborsEditField_4Label.Visible = true;
                app.NeighborsEditField_4.Visible = true;
            else
                app.NeighborsEditField_4Label.Visible = false;
                app.NeighborsEditField_4.Visible = false;
            end
            
            if strcmp(value, "Support Vector Machine")
                app.CostEditField_4Label.Visible = true;
                app.CostEditField_4.Visible = true;
                app.GammaEditField_4Label.Visible = true;
                app.GammaEditField_4.Visible = true;
            else
                app.CostEditField_4Label.Visible = false;
                app.CostEditField_4.Visible = false;
                app.GammaEditField_4Label.Visible = false;
                app.GammaEditField_4.Visible = false;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.PlotButton.Enable = false;
            pause(0.000005)
            try
                scenario = app.SelectDropDown.Value;
                scale = app.ScaleCheckBox.Value;
                assess = false;
                if app.KruskalWallisCheckBox.Value
                    technique = "KW";
                else
                    technique = app.ModelDropDown_3.Value;
                end
                n_features = app.NFeaturesEditField.Value;
                hyperparams.name = "None";
                classifier.name = "None";
                app_main(scenario, scale, assess, technique, n_features, hyperparams, classifier);
            catch ME
                disp(ME)
                app.ErrorLabel.Visible = true; 
                pause(2)
                app.ErrorLabel.Visible = false; 
            end
            app.PlotButton.Enable = true;
        end

        % Button pushed function: AssessButton_2
        function AssessButton_2Pushed(app, event)
            app.AssessButton_2.Enable = false;
            pause(0.000005)
            try
                scenario = app.SelectDropDown.Value;
                scale = app.ScaleCheckBox.Value;
                assess = true;
                technique = "None";
                n_features = 0;
                hyperparams.name = "None";
                classifier.name = "None";
                app_main(scenario, scale, assess, technique, n_features, hyperparams, classifier);
            catch ME
                disp(ME)
                app.ErrorLabel.Visible = true; 
                pause(2)
                app.ErrorLabel.Visible = false; 
            end
            app.AssessButton_2.Enable = true;
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.SearchButton.Enable = false;
            pause(0.000005)
            try
                scenario = app.SelectDropDown.Value;
                scale = app.ScaleCheckBox.Value;
                assess = false;
                model = app.ModelDropDown_2.Value;
                if app.KruskalWallisCheckBox.Value
                    technique = "KW";
                else
                    technique = app.ModelDropDown_3.Value;
                end
                n_features = app.NFeaturesEditField.Value;
                switch model
                    case "K - Nearest Neighbors"
                        hyperparams.name = "K - Nearest Neighbors";
                        hyperparams.neighbors = app.NeighborsEditField_3.Value;
                    case "Support Vector Machine"
                        hyperparams.name = "Support Vector Machine";
                        hyperparams.cost = app.CostEditField_3.Value;
                        hyperparams.gamma = app.GammaEditField_3.Value;
                    otherwise
                        disp("")
                end
                hyperparams.n_folds = app.NFoldsEditField.Value;
                classifier.name = "None";
                app_main(scenario, scale, assess, technique, n_features, hyperparams, classifier); 
            catch ME
                disp(ME)
                app.ErrorLabel.Visible = true; 
                pause(2)
                app.ErrorLabel.Visible = false; 
            end
            app.SearchButton.Enable = true;
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            app.ClassifyButton.Enable = false;
            pause(0.000005)
            try
                scenario = app.SelectDropDown.Value;
                scale = app.ScaleCheckBox.Value;
                assess = false;
                if app.KruskalWallisCheckBox.Value
                    technique = "KW";
                else
                    technique = app.ModelDropDown_3.Value;
                end
                n_features = app.NFeaturesEditField.Value;
                hyperparams.name = "None";
                classifier.name = app.ModelDropDown.Value;
                switch classifier.name
                    case "Euclidean Linear Discriminant"
                        
                    case "Mahalanobis Linear Discriminant"
                        
                    case "Fisher Linear Discriminant"
                        
                    case "K - Nearest Neighbors"
                        classifier.neighbors = app.NeighborsEditField_4.Value;
                    case "Bayesian Classifier"
                        
                    case "Support Vector Machine"
                        classifier.cost = app.CostEditField_4.Value;
                        classifier.gamma = app.GammaEditField_4.Value;
                    otherwise
                        disp("No classifer was specified!! in app")
                end
                [results, class_labels] = ...
                    app_main(scenario, scale, assess, technique, n_features, hyperparams, classifier);
                show_app = show;
                func(show_app, results, class_labels);
            catch ME
                disp(ME)
                app.ErrorLabel.Visible = true; 
                pause(2)
                app.ErrorLabel.Visible = false; 
            end
            app.ClassifyButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatternRecognition2022ProjectUIFigure = uifigure('Visible', 'off');
            app.PatternRecognition2022ProjectUIFigure.Name = 'Pattern Recognition @2022 Project';
            app.HeartDiseaseLabel = uilabel(app.PatternRecognition2022ProjectUIFigure);
            app.HeartDiseaseLabel.Text = 'Heart Disease';
            app.FeatureSelectionReductionPanel = uipanel(app.PatternRecognition2022ProjectUIFigure);
            app.FeatureSelectionReductionPanel.Title = '4 - Feature Selection / Reduction';
            app.SelectionPanel = uipanel(app.FeatureSelectionReductionPanel);
            app.SelectionPanel.Title = 'Selection';
            app.KruskalWallisCheckBox = uicheckbox(app.SelectionPanel);
            app.KruskalWallisCheckBox.ValueChangedFcn = createCallbackFcn(app, @KruskalWallisCheckBoxValueChanged, true);
            app.KruskalWallisCheckBox.Text = 'Kruskal-Wallis';
            app.ReductionPanel = uipanel(app.FeatureSelectionReductionPanel);
            app.ReductionPanel.Title = 'Reduction';
            app.ModelDropDown_3Label = uilabel(app.ReductionPanel);
            app.ModelDropDown_3Label.Text = 'Model';
            app.ModelDropDown_3 = uidropdown(app.ReductionPanel);
            app.ModelDropDown_3.Items = {'None', 'PCA', 'LDA', ''};
            app.ModelDropDown_3.Value = 'None';
            app.NFeaturesEditFieldLabel = uilabel(app.FeatureSelectionReductionPanel);
            app.NFeaturesEditFieldLabel.Text = 'N Features';
            app.NFeaturesEditField = uieditfield(app.FeatureSelectionReductionPanel, 'numeric');
            app.NFeaturesEditField.Limits = [1 Inf];
            app.NFeaturesEditField.Value = 4;
            app.PlotButton = uibutton(app.FeatureSelectionReductionPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClassificationPanel = uipanel(app.PatternRecognition2022ProjectUIFigure);
            app.ClassificationPanel.Title = '6 - Classification';
            app.ClassifyButton = uibutton(app.ClassificationPanel, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.ModelDropDownLabel = uilabel(app.ClassificationPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.ClassificationPanel);
            app.ModelDropDown.Items = {'Euclidean Linear Discriminant', 'Mahalanobis Linear Discriminant', 'Fisher Linear Discriminant', 'K - Nearest Neighbors', 'Support Vector Machine', 'Bayesian Classifier', ''};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Euclidean Linear Discriminant';
            app.CostEditField_4Label = uilabel(app.ClassificationPanel);
            app.CostEditField_4Label.Visible = 'off';
            app.CostEditField_4Label.Text = 'Cost';
            app.CostEditField_4 = uieditfield(app.ClassificationPanel, 'text');
            app.CostEditField_4.Visible = 'off';
            app.CostEditField_4.Value = '2^4';
            app.GammaEditField_4Label = uilabel(app.ClassificationPanel);
            app.GammaEditField_4Label.Visible = 'off';
            app.GammaEditField_4Label.Text = 'Gamma';
            app.GammaEditField_4 = uieditfield(app.ClassificationPanel, 'text');
            app.GammaEditField_4.Visible = 'off';
            app.GammaEditField_4.Value = '2^-5';
            app.NeighborsEditField_4Label = uilabel(app.ClassificationPanel);
            app.NeighborsEditField_4Label.Visible = 'off';
            app.NeighborsEditField_4Label.Text = 'Neighbors';
            app.NeighborsEditField_4 = uieditfield(app.ClassificationPanel, 'text');
            app.NeighborsEditField_4.Visible = 'off';
            app.NeighborsEditField_4.Value = '3';
            app.ScenariosPanel = uipanel(app.PatternRecognition2022ProjectUIFigure);
            app.ScenariosPanel.Title = '1 - Scenarios';
            app.SelectDropDownLabel = uilabel(app.ScenariosPanel);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.ScenariosPanel);
            app.SelectDropDown.Items = {'Scenario A', 'Scenario B', 'Scenario C', ''};
            app.SelectDropDown.ItemsData = {'1', '2', '3', ''};
            app.SelectDropDown.Value = '1';
            app.FeatureAssessmentPanel = uipanel(app.PatternRecognition2022ProjectUIFigure);
            app.FeatureAssessmentPanel.Title = '3 - Feature Assessment';
            app.AssessButton = uibutton(app.FeatureAssessmentPanel, 'push');
            app.AssessButton.Text = 'Assess';
            app.AssessButton_2 = uibutton(app.FeatureAssessmentPanel, 'push');
            app.AssessButton_2.ButtonPushedFcn = createCallbackFcn(app, @AssessButton_2Pushed, true);
            app.AssessButton_2.Text = 'Assess';
            app.PreprocessingPanel = uipanel(app.PatternRecognition2022ProjectUIFigure);
            app.PreprocessingPanel.Title = '2 - Pre-processing';
            app.ScaleCheckBox = uicheckbox(app.PreprocessingPanel);
            app.ScaleCheckBox.Text = 'Scale';
            app.HyperparametersTunningPanel = uipanel(app.PatternRecognition2022ProjectUIFigure);
            app.HyperparametersTunningPanel.Title = '5 - Hyper-parameters Tunning';
            app.SearchButton = uibutton(app.HyperparametersTunningPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.ModelDropDown_2Label = uilabel(app.HyperparametersTunningPanel);
            app.ModelDropDown_2Label.Text = 'Model';
            app.ModelDropDown_2 = uidropdown(app.HyperparametersTunningPanel);
            app.ModelDropDown_2.Items = {'K - Nearest Neighbors', 'Support Vector Machine'};
            app.ModelDropDown_2.ItemsData = {'K - Nearest Neighbors', 'Support Vector Machine'};
            app.ModelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModelDropDown_2ValueChanged, true);
            app.ModelDropDown_2.Value = 'K - Nearest Neighbors';
            app.NeighborsEditField_3Label = uilabel(app.HyperparametersTunningPanel);
            app.NeighborsEditField_3Label.Text = 'Neighbors';
            app.NeighborsEditField_3 = uieditfield(app.HyperparametersTunningPanel, 'text');
            app.NeighborsEditField_3.Value = '1:3';
            app.GammaEditField_3Label = uilabel(app.HyperparametersTunningPanel);
            app.GammaEditField_3Label.Visible = 'off';
            app.GammaEditField_3Label.Text = 'Gamma';
            app.GammaEditField_3 = uieditfield(app.HyperparametersTunningPanel, 'text');
            app.GammaEditField_3.Visible = 'off';
            app.GammaEditField_3.Value = '2.^(-20:2)';
            app.CostEditField_3Label = uilabel(app.HyperparametersTunningPanel);
            app.CostEditField_3Label.Visible = 'off';
            app.CostEditField_3Label.Text = 'Cost';
            app.CostEditField_3 = uieditfield(app.HyperparametersTunningPanel, 'text');
            app.CostEditField_3.Visible = 'off';
            app.CostEditField_3.Value = '2.^(-10:12)';
            app.NFoldsEditFieldLabel = uilabel(app.HyperparametersTunningPanel);
            app.NFoldsEditFieldLabel.Text = 'N Folds';
            app.NFoldsEditField = uieditfield(app.HyperparametersTunningPanel, 'numeric');
            app.NFoldsEditField.Limits = [2 Inf];
            app.NFoldsEditField.Value = 2;
            app.ErrorLabel = uilabel(app.PatternRecognition2022ProjectUIFigure);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            % Show the figure after all components are created
            app.PatternRecognition2022ProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatternRecognition2022ProjectUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatternRecognition2022ProjectUIFigure)
        end
    end
end