classdef show < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatternRecognition2022ProjectUIFigure  matlab.ui.Figure
        CloseButton                  matlab.ui.control.Button
        ClassificationReportLabel    matlab.ui.control.Label
        Class1Panel                  matlab.ui.container.Panel
        RecallEditFieldLabel         matlab.ui.control.Label
        RecallEditField              matlab.ui.control.EditField
        PrecisionEditFieldLabel      matlab.ui.control.Label
        PrecisionEditField           matlab.ui.control.EditField
        SpecificityEditFieldLabel    matlab.ui.control.Label
        SpecificityEditField         matlab.ui.control.EditField
        f1EditFieldLabel             matlab.ui.control.Label
        f1EditField                  matlab.ui.control.EditField
        Class2Panel                  matlab.ui.container.Panel
        RecallEditField_2Label       matlab.ui.control.Label
        RecallEditField_2            matlab.ui.control.EditField
        PrecisionEditField_2Label    matlab.ui.control.Label
        PrecisionEditField_2         matlab.ui.control.EditField
        SpecificityEditField_2Label  matlab.ui.control.Label
        SpecificityEditField_2       matlab.ui.control.EditField
        f1EditField_2Label           matlab.ui.control.Label
        f1EditField_2                matlab.ui.control.EditField
        Class3Panel                  matlab.ui.container.Panel
        RecallEditField_3Label       matlab.ui.control.Label
        RecallEditField_3            matlab.ui.control.EditField
        PrecisionEditField_3Label    matlab.ui.control.Label
        PrecisionEditField_3         matlab.ui.control.EditField
        SpecificityEditField_3Label  matlab.ui.control.Label
        SpecificityEditField_3       matlab.ui.control.EditField
        f1EditField_3Label           matlab.ui.control.Label
        f1EditField_3                matlab.ui.control.EditField
        AccuracyEditFieldLabel       matlab.ui.control.Label
        AccuracyEditField            matlab.ui.control.EditField
        LatexTableEditFieldLabel     matlab.ui.control.Label
        LatexTableEditField          matlab.ui.control.EditField
        CopyButton                   matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function results = func(app, results, class_labels)
            % Latex
            app.LatexTableEditField.Value = results.latex;
            
            % Titles
            app.Class1Panel.Title = "Class: " + class_labels(1);
            app.Class2Panel.Title = "Class: " + class_labels(2);
            
            
            % Metrics
            app.AccuracyEditField.Value = num2str(results.accuracy);
            
            % Class1
            app.RecallEditField.Value = num2str(results.classes{1}.recall);
            app.SpecificityEditField.Value = num2str(results.classes{1}.specificity);
            app.PrecisionEditField.Value = num2str(results.classes{1}.precision);
            app.f1EditField.Value = num2str(results.classes{1}.f1);
            
            % Class2
            app.RecallEditField_2.Value = num2str(results.classes{2}.recall);
            app.SpecificityEditField_2.Value = num2str(results.classes{2}.specificity);
            app.PrecisionEditField_2.Value = num2str(results.classes{2}.precision);
            app.f1EditField_2.Value = num2str(results.classes{2}.f1);
            
            if length(class_labels) == 3
                app.Class3Panel.Title = "Class: " + class_labels(3);
                app.RecallEditField_3.Value = num2str(results.classes{3}.recall);
                app.SpecificityEditField_3.Value = num2str(results.classes{3}.specificity);
                app.PrecisionEditField_3.Value = num2str(results.classes{3}.precision);
                app.f1EditField_3.Value = num2str(results.classes{3}.f1);
            else
                app.Class3Panel.Visible = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            clipboard('copy', app.LatexTableEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatternRecognition2022ProjectUIFigure = uifigure('Visible', 'off');
            app.PatternRecognition2022ProjectUIFigure.Name = 'Pattern Recognition @2022 Project';
            app.CloseButton = uibutton(app.PatternRecognition2022ProjectUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ClassificationReportLabel = uilabel(app.PatternRecognition2022ProjectUIFigure);
            app.ClassificationReportLabel.Text = 'Classification Report';
            app.Class1Panel = uipanel(app.PatternRecognition2022ProjectUIFigure);
            app.Class1Panel.Title = 'Class1';
            app.RecallEditFieldLabel = uilabel(app.Class1Panel);
            app.RecallEditFieldLabel.Text = 'Recall';
            app.RecallEditField = uieditfield(app.Class1Panel, 'text');
            app.RecallEditField.Editable = 'off';
            app.PrecisionEditFieldLabel = uilabel(app.Class1Panel);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.Class1Panel, 'text');
            app.PrecisionEditField.Editable = 'off';
            app.SpecificityEditFieldLabel = uilabel(app.Class1Panel);
            app.SpecificityEditFieldLabel.Text = 'Specificity';
            app.SpecificityEditField = uieditfield(app.Class1Panel, 'text');
            app.SpecificityEditField.Editable = 'off';
            app.f1EditFieldLabel = uilabel(app.Class1Panel);
            app.f1EditFieldLabel.Text = 'f1';
            app.f1EditField = uieditfield(app.Class1Panel, 'text');
            app.f1EditField.Editable = 'off';
            app.Class2Panel = uipanel(app.PatternRecognition2022ProjectUIFigure);
            app.Class2Panel.Title = 'Class2';
            app.RecallEditField_2Label = uilabel(app.Class2Panel);
            app.RecallEditField_2Label.Text = 'Recall';
            app.RecallEditField_2 = uieditfield(app.Class2Panel, 'text');
            app.RecallEditField_2.Editable = 'off';
            app.PrecisionEditField_2Label = uilabel(app.Class2Panel);
            app.PrecisionEditField_2Label.Text = 'Precision';
            app.PrecisionEditField_2 = uieditfield(app.Class2Panel, 'text');
            app.PrecisionEditField_2.Editable = 'off';
            app.SpecificityEditField_2Label = uilabel(app.Class2Panel);
            app.SpecificityEditField_2Label.Text = 'Specificity';
            app.SpecificityEditField_2 = uieditfield(app.Class2Panel, 'text');
            app.SpecificityEditField_2.Editable = 'off';
            app.f1EditField_2Label = uilabel(app.Class2Panel);
            app.f1EditField_2Label.Text = 'f1';
            app.f1EditField_2 = uieditfield(app.Class2Panel, 'text');
            app.f1EditField_2.Editable = 'off';
            app.Class3Panel = uipanel(app.PatternRecognition2022ProjectUIFigure);
            app.Class3Panel.Title = 'Class3';
            app.RecallEditField_3Label = uilabel(app.Class3Panel);
            app.RecallEditField_3Label.Text = 'Recall';
            app.RecallEditField_3 = uieditfield(app.Class3Panel, 'text');
            app.RecallEditField_3.Editable = 'off';
            app.PrecisionEditField_3Label = uilabel(app.Class3Panel);
            app.PrecisionEditField_3Label.Text = 'Precision';
            app.PrecisionEditField_3 = uieditfield(app.Class3Panel, 'text');
            app.PrecisionEditField_3.Editable = 'off';
            app.SpecificityEditField_3Label = uilabel(app.Class3Panel);
            app.SpecificityEditField_3Label.Text = 'Specificity';
            app.SpecificityEditField_3 = uieditfield(app.Class3Panel, 'text');
            app.SpecificityEditField_3.Editable = 'off';
            app.f1EditField_3Label = uilabel(app.Class3Panel);
            app.f1EditField_3Label.Text = 'f1';
            app.f1EditField_3 = uieditfield(app.Class3Panel, 'text');
            app.f1EditField_3.Editable = 'off';
            app.AccuracyEditFieldLabel = uilabel(app.PatternRecognition2022ProjectUIFigure);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.PatternRecognition2022ProjectUIFigure, 'text');
            app.AccuracyEditField.Editable = 'off';
            app.LatexTableEditFieldLabel = uilabel(app.PatternRecognition2022ProjectUIFigure);
            app.LatexTableEditFieldLabel.Text = 'Latex Table';
            app.LatexTableEditField = uieditfield(app.PatternRecognition2022ProjectUIFigure, 'text');
            app.CopyButton = uibutton(app.PatternRecognition2022ProjectUIFigure, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            % Show the figure after all components are created
            app.PatternRecognition2022ProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = show

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatternRecognition2022ProjectUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatternRecognition2022ProjectUIFigure)
        end
    end
end